classdef VRS_VisualizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LightCharachtericticAppPanel  matlab.ui.container.Panel
        AngleEditField                matlab.ui.control.NumericEditField
        AngleEditFieldLabel           matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        LuxTab                        matlab.ui.container.Tab
        IlluminancePanel              matlab.ui.container.Panel
        CCTTab                        matlab.ui.container.Tab
        ColorTemperaturePanel         matlab.ui.container.Panel
        BeginScanButton               matlab.ui.control.Button
        TurnOffSensorButton           matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        InitializeSensorButton        matlab.ui.control.Button
    end

    
    properties 
        device = serialport("COM8",38400);
        data;
        dataToSend;
        response;
        splitData;
        splitData2;
        scanStarted = 0;
        parsedData;
        sensorOn = 0;
        flag = 0;
        lux = 0;
        cct = 0;
        angle = 0;
        t1;
        pax;
        pax2;
        angleSend;
        error = 1;
    end
    
    methods (Access = public)
        
        function getData(app)
            if(app.scanStarted == 1)
                app.data = read(app.device, 1, 'char');
                if(app.flag == 1)
                    app.splitData = [app.splitData app.splitData2];
                    app.splitData2 = 0;
                    app.flag = 0;
                end
                for i = 1:length(app.data)
                    if(app.data(i) == 'o')
                        break;
                    end
                    if(app.data(i) ~= 'x' && app.flag == 0)
                        app.splitData = [app.splitData app.data(i)];
                    elseif(app.data(i) ~= 'x')
                        app.splitData2 = [app.splitData2 app.data(i)];
                    else
                        app.flag = 1;
                    end
                end
                if(app.flag == 1)
%                     app.parsedData = split(app.splitData, ',');
%                     app.parsedData = cell2mat(transpose(app.parsedData));
                    app.parsedData = str2num(app.splitData);
                    app.lux = [app.lux app.parsedData(1)];
                    app.cct = [app.cct app.parsedData(2)];
                    app.angle = [app.angle app.parsedData(end)];
                    % app.Label.Text = num2str(app.splitData);
                    app.splitData = 0;
                end
                if(app.data(end) == 'o')
                    app.scanStarted = 0;
                    app.Lamp.Color = 'red';
                    app.sensorOn = 0;
                    app.lux(1) = [];
                    app.cct(1) = [];
                    app.angle(1) = [];
%                     for i = 1:length(app.angle)
%                         rectangle(app.UIAxes, 'Position', [app.angle(i), 0, 5, app.lux(i)],'EdgeColor', 'k','FaceColor', 'b')
%                         rectangle(app.UIAxes2, 'Position', [app.angle(i), 0, 5, app.cct(i)],'EdgeColor', 'k','FaceColor', 'r')
                          polarplot(app.pax,deg2rad(app.angle),app.lux, 'color', 'b', 'lineWidth', 1.2)
                          polarplot(app.pax2,deg2rad(app.angle),app.cct, 'color', 'r', 'lineWidth', 1.2)
%                     end
%                     app.UIAxes.YLim = [0 max(app.lux)+0.1*max(app.lux)];
%                     app.UIAxes2.YLim = [0 max(app.cct)+0.1*max(app.cct)];
%                     app.UIAxes.XLim = [0 200];
%                     app.UIAxes.XTick = 0:45:200;
%                     app.UIAxes.XLabel.String = 'φ [°]';
%                     app.UIAxes.YLabel.String = 'E_v [lx]';
%                     app.UIAxes.Title.String = 'Illuminance';
%                     app.UIAxes2.XLim = [0 200];
%                     app.UIAxes2.XTick = 0:45:200;
%                     app.UIAxes2.XLabel.String = 'φ [°]';
%                     app.UIAxes2.YLabel.String = 'CCT [K]';
%                     app.UIAxes2.Title.String = 'Color temperature';
                    app.angle = 0;
                    app.lux = 0;
                    app.cct = 0;
                    stop(app.t1);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t1 = timer('TimerFcn', @(~,~)getData(app), 'Period', 0.01, 'ExecutionMode', 'fixedRate');
%             app.UIAxes.XLim = [0 200];
%             app.UIAxes.XTick = 0:45:200;
%             app.UIAxes.XLabel.String = 'φ [°]';
%             app.UIAxes.YLim = [0 10000];
%             app.UIAxes.YLabel.String = 'E_v [lx]';
%             app.UIAxes2.XLim = [0 200];
%             app.UIAxes2.XTick = 0:45:200;
%             app.UIAxes2.XLabel.String = 'φ [°]';
%             app.UIAxes2.YLim = [0 20000];
%             app.UIAxes2.YLabel.String = 'CCT [K]';
              app.pax = polaraxes(app.IlluminancePanel);
              app.pax2 = polaraxes(app.ColorTemperaturePanel);
        end

        % Button pushed function: InitializeSensorButton
        function InitializeSensorButtonPushed(app, event)
            if(app.error == 0)
                app.dataToSend = 49;
                temp = app.device.NumBytesAvailable;
                write(app.device,app.dataToSend,"uint8");
    %             pause(0.1);
                app.response = read(app.device, temp+1, 'char');
                pause(0.1);
                if(app.response(end) == '1')
                    write(app.device,app.angleSend,"uint8");
                    app.Lamp.Color = 'green';
                    app.sensorOn = 1;
                end
            else
                f = errordlg('Initialize the angle first.','Error');
            end
        end

        % Button pushed function: TurnOffSensorButton
        function TurnOffSensorButtonPushed(app, event)
            app.dataToSend = 50;
            temp = app.device.NumBytesAvailable;
            write(app.device,app.dataToSend,"uint8");
%             pause(0.1);
            app.response = read(app.device, temp+1, 'char');
            if(app.response(end) == '2')
                app.Lamp.Color = 'red';
                app.sensorOn = 0;
                stop(app.t1);
            end
        end

        % Button pushed function: BeginScanButton
        function BeginScanButtonPushed(app, event)
            app.dataToSend = 51;
            if(app.sensorOn == 1)
                temp = app.device.NumBytesAvailable;
                write(app.device,app.dataToSend,"uint8");
%                 pause(0.1);
                app.response = read(app.device, temp+1, 'char');
                if(app.response(end) == '3')
                    app.Lamp.Color = 'yellow';
                    app.scanStarted = 1;
%                     cla(app.UIAxes, 'reset');
%                     cla(app.UIAxes2, 'reset');
                end
                start(app.t1);
            end
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            if(value <= 30 && value > 0)
                app.angleSend = value;
                app.error = 0;
            else
                app.error = 1;
                f = errordlg('The angle must be between 0 and 30 degrees','Error evaluating angle');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LightCharachtericticAppPanel = uipanel(app.UIFigure);
            app.LightCharachtericticAppPanel.Title = 'LightCharachtericticApp';
            app.InitializeSensorButton = uibutton(app.LightCharachtericticAppPanel, 'push');
            app.InitializeSensorButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeSensorButtonPushed, true);
            app.InitializeSensorButton.Text = 'Initialize Sensor';
            app.Lamp = uilamp(app.LightCharachtericticAppPanel);
            app.TurnOffSensorButton = uibutton(app.LightCharachtericticAppPanel, 'push');
            app.TurnOffSensorButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffSensorButtonPushed, true);
            app.TurnOffSensorButton.Text = 'Turn Off Sensor';
            app.BeginScanButton = uibutton(app.LightCharachtericticAppPanel, 'push');
            app.BeginScanButton.ButtonPushedFcn = createCallbackFcn(app, @BeginScanButtonPushed, true);
            app.BeginScanButton.Text = 'Begin Scan';
            app.TabGroup = uitabgroup(app.LightCharachtericticAppPanel);
            app.LuxTab = uitab(app.TabGroup);
            app.LuxTab.Title = 'Lux';
            app.IlluminancePanel = uipanel(app.LuxTab);
            app.IlluminancePanel.Title = 'Illuminance';
            app.CCTTab = uitab(app.TabGroup);
            app.CCTTab.Title = 'CCT';
            app.ColorTemperaturePanel = uipanel(app.CCTTab);
            app.ColorTemperaturePanel.Title = 'ColorTemperature';
            app.AngleEditFieldLabel = uilabel(app.LightCharachtericticAppPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.LightCharachtericticAppPanel, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VRS_VisualizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end